classdef convertorGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConvertorUIFigure               matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SlectiondesfichiersButton       matlab.ui.control.Button
        DestinationButton               matlab.ui.control.Button
        ConversionButton                matlab.ui.control.StateButton
        EditField                       matlab.ui.control.EditField
        ConversionPanel                 matlab.ui.container.Panel
        NettoyagedelamesureCheckBox     matlab.ui.control.CheckBox
        RecentragedelamplitudeCheckBox  matlab.ui.control.CheckBox
        RightPanel                      matlab.ui.container.Panel
        OptionsMenu                     matlab.ui.container.Menu
        LectureMenu                     matlab.ui.container.Menu
        EcritureMenu                    matlab.ui.container.Menu
        AideMenu                        matlab.ui.container.Menu
        AproposMenu                     matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Folder % Folder to scan
        Filenames % Filenames to convert
        Destination % Destination folder for the files
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SlectiondesfichiersButton
        function SlectiondesfichiersButtonPushed(app, event)
            [app.Filenames, app.Folder] = uigetfile(...
                {'*.asc', 'asc (ASCII) files'}, ...
                'Sélection des fichiers', ...
                '', ...
                'MultiSelect', 'on');
        end

        % Button pushed function: DestinationButton
        function DestinationButtonPushed(app, event)
            if isempty(app.Folder)
                dest = '';
            else
                dest = app.Folder;
            end
            
            [app.Destination] = uigetdir(dest, 'Sélection du dossier de destination');
            
            app.EditField.Value = app.Destination;
        end

        % Value changed function: ConversionButton
        function ConversionButtonValueChanged(app, event)
            value = app.ConversionButton.Value;
            singleFile = 1;
            
            if iscell(app.Filenames)
                singleFile = 0;
                uialert(app.ConvertorUIFigure, ...
                    {'Traitement sans affichage', 'Sélection de plusieurs fichiers'}, ...
                    'Attention', ...
                    'Icon', 'warning' ...
                );
            end
            
            if ~iscell(app.Filenames)
                app.Filenames = {app.Filenames};
            end

            p = uiprogressdlg(app.ConvertorUIFigure, ...
                'Title', 'Conversion en cours', ... 
                'Message', 'Lancement du traitement');
            
            nbFiles = length(app.Filenames);
            
            for f = 1:length(app.Filenames)
                p.Value = 1 - (nbFiles / f);
                filename = fullfile(app.Folder, app.Filenames{f});
                p.Message = sprintf('Fichier : %s', filename);
                
                if singleFile
                    app.displayFigure()
                end
            end
            
            p.Value = 1;
            p.Message = 'Conversion terminée';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ConvertorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {534, 534};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {345, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConvertorUIFigure = uifigure('Visible', 'off');
            app.ConvertorUIFigure.Name = 'Convertor';
            app.ConvertorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ConvertorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SlectiondesfichiersButton = uibutton(app.LeftPanel, 'push');
            app.SlectiondesfichiersButton.ButtonPushedFcn = createCallbackFcn(app, @SlectiondesfichiersButtonPushed, true);
            app.SlectiondesfichiersButton.Text = 'Sélection des fichiers';
            app.DestinationButton = uibutton(app.LeftPanel, 'push');
            app.DestinationButton.ButtonPushedFcn = createCallbackFcn(app, @DestinationButtonPushed, true);
            app.DestinationButton.Text = 'Destination';
            app.ConversionButton = uibutton(app.LeftPanel, 'state');
            app.ConversionButton.ValueChangedFcn = createCallbackFcn(app, @ConversionButtonValueChanged, true);
            app.ConversionButton.Text = 'Conversion';
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.ConversionPanel = uipanel(app.LeftPanel);
            app.ConversionPanel.Title = 'Conversion';
            app.NettoyagedelamesureCheckBox = uicheckbox(app.ConversionPanel);
            app.NettoyagedelamesureCheckBox.Text = 'Nettoyage de la mesure';
            app.NettoyagedelamesureCheckBox.Value = true;
            app.RecentragedelamplitudeCheckBox = uicheckbox(app.ConversionPanel);
            app.RecentragedelamplitudeCheckBox.Text = 'Recentrage de l''amplitude';
            app.RecentragedelamplitudeCheckBox.Value = true;
            app.RightPanel = uipanel(app.GridLayout);
            app.OptionsMenu = uimenu(app.ConvertorUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.LectureMenu = uimenu(app.OptionsMenu);
            app.LectureMenu.Text = 'Lecture';
            app.EcritureMenu = uimenu(app.OptionsMenu);
            app.EcritureMenu.Text = 'Ecriture';
            app.AideMenu = uimenu(app.ConvertorUIFigure);
            app.AideMenu.Text = 'Aide';
            app.AproposMenu = uimenu(app.AideMenu);
            app.AproposMenu.Text = 'A propos';
            % Show the figure after all components are created
            app.ConvertorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = convertorGUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ConvertorUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.ConvertorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConvertorUIFigure)
        end
    end
end