classdef convertorSimpleGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConvertisseurUIFigure         matlab.ui.Figure
        SlectiondesfichiersButton     matlab.ui.control.Button
        LancementButton               matlab.ui.control.Button
        DestinationPanel              matlab.ui.container.Panel
        DossierButton                 matlab.ui.control.Button
        EditField                     matlab.ui.control.EditField
        ConversionPanel               matlab.ui.container.Panel
        NettoyagedelamesureCheckBox   matlab.ui.control.CheckBox
        RecentragedelamesureCheckBox  matlab.ui.control.CheckBox
        BitsDropDownLabel             matlab.ui.control.Label
        BitsDropDown                  matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        Folder % Description
        Filenames % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SlectiondesfichiersButton
        function SlectiondesfichiersButtonPushed(app, event)
            [app.Filenames, app.Folder] = uigetfile(... 
                {'*.asc', 'ASC files'}, ...
                'Sélection des fichiers', ...
                [], ...
                'Multiselect', 'on');
            
            if ~iscell(app.Filenames)
                app.Filenames = {app.Filenames};
            end
            
            app.EditField.Value = app.Folder; 
        end

        % Button pushed function: DossierButton
        function DossierButtonPushed(app, event)
            if isempty(app.Folder)
                folder = '';
            else
                folder = app.Folder;
            end
            
            dest = uigetdir(folder, 'Sélection du dossier de destination');
            
            app.EditField.Value = dest;
        end

        % Button pushed function: LancementButton
        function LancementButtonPushed(app, event)
            if isempty(app.Filenames)
                uialert(app.ConvertisseurUIFigure, 'Erreur ...', 'Aucun fichier sélectionné', "Icon", 'error');
            end
            
            destFolder = app.EditField.Value;
            
            p = uiprogressdlg(app.ConvertisseurUIFigure, ...
                'Title', 'Conversion en cours', ...
                'Message', 'Initialisation du converteur');
            
            analysis.clean = app.NettoyagedelamesureCheckBox.Value;
            analysis.recenter = app.RecentragedelamesureCheckBox.Value;
            
            sdfOptions.bits = app.BitsDropDown.Value;
            
            convertor = Convertor([], sdfOptions, analysis);
            
            p.Value = 0.05;
            p.Message = 'Traitement des fichiers';
            
            status = zeros(size(app.Filenames));
            
            nbFiles = length(app.Filenames);
            step = (1/nbFiles);
            for f = 1:nbFiles
                filename = app.Filenames{f};
                filepath = fullfile(app.Folder, filename);
                
                
                p.Message = sprintf("Fichier (%d/%d) : %10s", ...
                    f, nbFiles, ...
                    utils.shortenFilename(filename) ...
                );
                
                status(f) = convertor.convert(filepath, destFolder);
                
                if step == 1
                    p.Value = 1;
                else
                    p.Value = p.Value + step;
                end
                
            end
            
            p.Value = 1;
            p.Message = 'Conversion terminée';
            
            if sum(status)/length(status) == 1
                uialert(app.ConvertisseurUIFigure, 'Aucune erreur', 'Succès de la conversion', 'Icon', 'success');
            else
                uialert(app.ConvertisseurUIFigure, ...
                    sprintf('Erreur sur %d fichier(s)', sum(~status)), ...
                    'Echec de la conversion', ...
                    'Icon', 'error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConvertisseurUIFigure = uifigure('Visible', 'off');
            app.ConvertisseurUIFigure.Name = 'Convertisseur';
            app.SlectiondesfichiersButton = uibutton(app.ConvertisseurUIFigure, 'push');
            app.SlectiondesfichiersButton.ButtonPushedFcn = createCallbackFcn(app, @SlectiondesfichiersButtonPushed, true);
            app.SlectiondesfichiersButton.Text = 'Sélection des fichiers';
            app.LancementButton = uibutton(app.ConvertisseurUIFigure, 'push');
            app.LancementButton.ButtonPushedFcn = createCallbackFcn(app, @LancementButtonPushed, true);
            app.LancementButton.Text = 'Lancement';
            app.DestinationPanel = uipanel(app.ConvertisseurUIFigure);
            app.DestinationPanel.Title = 'Destination';
            app.DossierButton = uibutton(app.DestinationPanel, 'push');
            app.DossierButton.ButtonPushedFcn = createCallbackFcn(app, @DossierButtonPushed, true);
            app.DossierButton.Text = 'Dossier';
            app.EditField = uieditfield(app.DestinationPanel, 'text');
            app.ConversionPanel = uipanel(app.ConvertisseurUIFigure);
            app.ConversionPanel.Title = 'Conversion';
            app.NettoyagedelamesureCheckBox = uicheckbox(app.ConversionPanel);
            app.NettoyagedelamesureCheckBox.Text = 'Nettoyage de la mesure';
            app.NettoyagedelamesureCheckBox.Value = true;
            app.RecentragedelamesureCheckBox = uicheckbox(app.ConversionPanel);
            app.RecentragedelamesureCheckBox.Text = 'Recentrage de la mesure';
            app.RecentragedelamesureCheckBox.Value = true;
            app.BitsDropDownLabel = uilabel(app.ConversionPanel);
            app.BitsDropDownLabel.Text = 'Bits';
            app.BitsDropDown = uidropdown(app.ConversionPanel);
            app.BitsDropDown.Items = {'16', '32'};
            app.BitsDropDown.Value = '32';
            % Show the figure after all components are created
            app.ConvertisseurUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = convertorSimpleGUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ConvertisseurUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.ConvertisseurUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConvertisseurUIFigure)
        end
    end
end